classdef sim_interf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LidarPoint                  matlab.ui.control.EditField
        CoordenadasEditFieldLabel   matlab.ui.control.Label
        LidarVec                    matlab.ui.control.EditField
        DireoEditFieldLabel         matlab.ui.control.Label
        PontoObj                    matlab.ui.control.EditField
        PontoEditField_3Label       matlab.ui.control.Label
        PontoRef2                   matlab.ui.control.EditField
        PontoEditField_2Label       matlab.ui.control.Label
        PontoRef1                   matlab.ui.control.EditField
        PontoEditFieldLabel         matlab.ui.control.Label
        Beta1Obj                    matlab.ui.control.EditField
        BetaEditField_3Label        matlab.ui.control.Label
        Beta1Ref2                   matlab.ui.control.EditField
        BetaEditField_2Label        matlab.ui.control.Label
        Beta1Ref1                   matlab.ui.control.EditField
        BetaEditFieldLabel          matlab.ui.control.Label
        Alpha1Obj                   matlab.ui.control.EditField
        AlphaEditField_3Label       matlab.ui.control.Label
        Alpha1Ref2                  matlab.ui.control.EditField
        AlphaEditField_2Label       matlab.ui.control.Label
        Alpha1Ref1                  matlab.ui.control.EditField
        AlphaEditFieldLabel         matlab.ui.control.Label
        PUSHButton                  matlab.ui.control.Button
        LiDARLabel                  matlab.ui.control.Label
        PlanodoObjLabel             matlab.ui.control.Label
        Planoderef2Label            matlab.ui.control.Label
        Planoderef1Label            matlab.ui.control.Label
        ParametrizaodosPlanosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PUSHButton
        function PUSHButtonPushed(app, event)
            sim_v4(app.Alpha1Ref1.Value,app.Alpha1Ref2.Value,app.Alpha1Obj.Value,app.Beta1Ref1.Value,app.Beta1Ref2.Value,app.Beta1Obj.Value,app.PontoRef1.Value,app.PontoRef2.Value,app.PontoObj.Value,app.LidarVec.Value,app.LidarPoint.Value);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametrizaodosPlanosLabel = uilabel(app.UIFigure);
            app.ParametrizaodosPlanosLabel.Text = 'Parametrização dos Planos';
            app.Planoderef1Label = uilabel(app.UIFigure);
            app.Planoderef1Label.Text = 'Plano de ref 1:';
            app.Planoderef2Label = uilabel(app.UIFigure);
            app.Planoderef2Label.Text = 'Plano de ref 2:';
            app.PlanodoObjLabel = uilabel(app.UIFigure);
            app.PlanodoObjLabel.Text = 'Plano do Obj:';
            app.LiDARLabel = uilabel(app.UIFigure);
            app.LiDARLabel.Text = 'LiDAR';
            app.PUSHButton = uibutton(app.UIFigure, 'push');
            app.PUSHButton.ButtonPushedFcn = createCallbackFcn(app, @PUSHButtonPushed, true);
            app.PUSHButton.Text = 'PUSH';
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.Alpha1Ref1 = uieditfield(app.UIFigure, 'text');
            app.Alpha1Ref1.Value = '0 -2 4';
            app.AlphaEditField_2Label = uilabel(app.UIFigure);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.Alpha1Ref2 = uieditfield(app.UIFigure, 'text');
            app.Alpha1Ref2.Value = '0 5 0';
            app.AlphaEditField_3Label = uilabel(app.UIFigure);
            app.AlphaEditField_3Label.Text = 'Alpha';
            app.Alpha1Obj = uieditfield(app.UIFigure, 'text');
            app.Alpha1Obj.Value = '0 0 4';
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.Beta1Ref1 = uieditfield(app.UIFigure, 'text');
            app.Beta1Ref1.Value = '5 0 0';
            app.BetaEditField_2Label = uilabel(app.UIFigure);
            app.BetaEditField_2Label.Text = 'Beta';
            app.Beta1Ref2 = uieditfield(app.UIFigure, 'text');
            app.Beta1Ref2.Value = '5 0 0';
            app.BetaEditField_3Label = uilabel(app.UIFigure);
            app.BetaEditField_3Label.Text = 'Beta';
            app.Beta1Obj = uieditfield(app.UIFigure, 'text');
            app.Beta1Obj.Value = '5 0 5';
            app.PontoEditFieldLabel = uilabel(app.UIFigure);
            app.PontoEditFieldLabel.Text = 'Ponto';
            app.PontoRef1 = uieditfield(app.UIFigure, 'text');
            app.PontoRef1.Value = '10 -5 5';
            app.PontoEditField_2Label = uilabel(app.UIFigure);
            app.PontoEditField_2Label.Text = 'Ponto';
            app.PontoRef2 = uieditfield(app.UIFigure, 'text');
            app.PontoRef2.Value = '10 -5 15';
            app.PontoEditField_3Label = uilabel(app.UIFigure);
            app.PontoEditField_3Label.Text = 'Ponto';
            app.PontoObj = uieditfield(app.UIFigure, 'text');
            app.PontoObj.Value = '10 10 5';
            app.DireoEditFieldLabel = uilabel(app.UIFigure);
            app.DireoEditFieldLabel.Text = 'Direção';
            app.LidarVec = uieditfield(app.UIFigure, 'text');
            app.LidarVec.Value = '0.2 0.1 1';
            app.CoordenadasEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadasEditFieldLabel.Text = 'Coordenadas';
            app.LidarPoint = uieditfield(app.UIFigure, 'text');
            app.LidarPoint.Value = '5 5 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sim_interf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end